classdef ProjectileMotionCalc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % Project...
        UIAxes                 matlab.ui.control.UIAxes           % Trajectory
        InitCondsPanel         matlab.ui.container.Panel          % Initial...
        LabelNumericEditField  matlab.ui.control.Label            % Velocity
        VelocityField          matlab.ui.control.NumericEditField % [0 200]
        LabelNumericEditField2 matlab.ui.control.Label            % Launch ...
        ThetaField             matlab.ui.control.NumericEditField % [0 90]
        LabelNumericEditField3 matlab.ui.control.Label            % Initial...
        y0Field                matlab.ui.control.NumericEditField % [0 300]
        Label4                 matlab.ui.control.Label            % Gravity...
        GravityField           matlab.ui.control.NumericEditField % [0 Inf]
        DragPanel              matlab.ui.container.Panel          % Air Res...
        LabelSwitch            matlab.ui.control.Label            % Linear
        LinToggle              matlab.ui.control.Switch           % Off, On
        LabelSwitch2           matlab.ui.control.Label            % Quadratic
        QuadToggle             matlab.ui.control.Switch           % Off, On
        ObjPropsPanel          matlab.ui.container.Panel          % Object ...
        LabelNumericEditField4 matlab.ui.control.Label            % Mass
        ObjMassField           matlab.ui.control.NumericEditField % [0 Inf]
        Label                  matlab.ui.control.Label            % Diameter
        ObjDiaField            matlab.ui.control.NumericEditField % [0 Inf]
        MedPropsPanel          matlab.ui.container.Panel          % Medium ...
        Label2                 matlab.ui.control.Label            % Beta
        BetaField              matlab.ui.control.NumericEditField % [0 Inf]
        Label3                 matlab.ui.control.Label            % Gamma
        GammaField             matlab.ui.control.NumericEditField % [0 Inf]
        MedPropsDefaultButton  matlab.ui.control.Button           % Default
        ResultsPanel           matlab.ui.container.Panel          % Results
        LabelNumericEditField5 matlab.ui.control.Label            % Range
        RangeField             matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField6 matlab.ui.control.Label            % Maximum...
        MaxHeightField         matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField7 matlab.ui.control.Label            % Time of...
        FlightTimeField        matlab.ui.control.NumericEditField % [-Inf Inf]
        ImportDataButton       matlab.ui.control.Button           % Import ...
        ImportHelpButton       matlab.ui.control.Button           % ?
        ActionPanel            matlab.ui.container.Panel         
        CalcButton             matlab.ui.control.Button           % Calculate
        ResetParamsButton      matlab.ui.control.Button           % Reset P...
        ClearPlotsButton       matlab.ui.control.Button           % Clear P...
        LabelSwitch3           matlab.ui.control.Label            % Combine...
        CombinePlotsToggle     matlab.ui.control.Switch           % Off, On
    end

    
    properties (Access = private)
        dragType %String corresponding to active drag properties
        mediumProps
        objectProps
        r
        rPositive
        maxPoint
        
        dataFileName
        dataPathName
        paramData
        resultData
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.dragType = 'none';
        end

        % MedPropsDefaultButton button pushed function
        function MedPropsDefaultButtonPushed(app)
            app.BetaField.Value = 0.00016;
            app.GammaField.Value = 0.25;
        end

        % LinToggle value changed function
        function LinToggleValueChanged(app)
            if strcmp(app.LinToggle.Value, 'On') && strcmp(app.QuadToggle.Value, 'On')
                
                app.dragType = 'linquad';
                
                app.ObjMassField.Enable = 'On';
                app.ObjDiaField.Enable = 'On';
                app.BetaField.Enable = 'On';
                app.GammaField.Enable = 'On';
                app.MedPropsDefaultButton.Enable = 'On';
                
            elseif strcmp(app.LinToggle.Value, 'On') && strcmp(app.QuadToggle.Value, 'Off')
                
                app.dragType = 'lin';
                
                app.ObjMassField.Enable = 'On';
                app.ObjDiaField.Enable = 'On';
                app.BetaField.Enable = 'On';
                app.GammaField.Enable = 'Off';
                app.MedPropsDefaultButton.Enable = 'On';
                
            elseif strcmp(app.LinToggle.Value, 'Off') && strcmp(app.QuadToggle.Value, 'On')
                
                app.dragType = 'quad';
                
                app.ObjMassField.Enable = 'On';
                app.ObjDiaField.Enable = 'On';
                app.BetaField.Enable = 'Off';
                app.GammaField.Enable = 'On';
                app.MedPropsDefaultButton.Enable = 'On';
                
            elseif strcmp(app.LinToggle.Value, 'Off') && strcmp(app.QuadToggle.Value, 'Off')
                
                app.dragType = 'none';
                
                app.ObjMassField.Enable = 'Off';
                app.ObjDiaField.Enable = 'Off';
                app.BetaField.Enable = 'Off';
                app.GammaField.Enable = 'Off';
                app.MedPropsDefaultButton.Enable = 'Off';
                
            end
        end

        % QuadToggle value changed function
        function QuadToggleValueChanged(app)
            if strcmp(app.LinToggle.Value, 'On') && strcmp(app.QuadToggle.Value, 'On')
                
                app.dragType = 'linquad';
                
                app.ObjMassField.Enable = 'On';
                app.ObjDiaField.Enable = 'On';
                app.BetaField.Enable = 'On';
                app.GammaField.Enable = 'On';
                app.MedPropsDefaultButton.Enable = 'On';
                
            elseif strcmp(app.LinToggle.Value, 'On') && strcmp(app.QuadToggle.Value, 'Off')
                
                app.dragType = 'lin';
                
                app.ObjMassField.Enable = 'On';
                app.ObjDiaField.Enable = 'On';
                app.BetaField.Enable = 'On';
                app.GammaField.Enable = 'Off';
                app.MedPropsDefaultButton.Enable = 'On';
                
            elseif strcmp(app.LinToggle.Value, 'Off') && strcmp(app.QuadToggle.Value, 'On')
                
                app.dragType = 'quad';
                
                app.ObjMassField.Enable = 'On';
                app.ObjDiaField.Enable = 'On';
                app.BetaField.Enable = 'Off';
                app.GammaField.Enable = 'On';
                app.MedPropsDefaultButton.Enable = 'On';
                
            elseif strcmp(app.LinToggle.Value, 'Off') && strcmp(app.QuadToggle.Value, 'Off')
                
                app.dragType = 'none';
                
                app.ObjMassField.Enable = 'Off';
                app.ObjDiaField.Enable = 'Off';
                app.BetaField.Enable = 'Off';
                app.GammaField.Enable = 'Off';
                app.MedPropsDefaultButton.Enable = 'Off';
                
            end
        end

        % ResetParamsButton button pushed function
        function ResetParamsButtonButtonPushed(app)
            app.VelocityField.Value = 1;
            app.ThetaField.Value = 0;
            app.y0Field.Value = 0;
            app.GravityField.Value = 9.81;
            app.ObjMassField.Value = 1;
            app.ObjDiaField.Value = 1;
            app.BetaField.Value = 0.00016;
            app.GammaField.Value = 0.25;
        end

        % CalcButton button pushed function
        function CalcButtonPushed(app)
            if app.ThetaField.Value <= 0 && app.y0Field.Value == 0
                uiwait(helpdlg('Invalid input, object will travel 0 distance. Either Launch Angle or Initial Height must be greater than zero', 'Input Error'));
            else
                
                app.mediumProps = struct('beta', app.BetaField.Value, 'gamma', app.GammaField.Value);
                app.objectProps = struct('mass', app.ObjMassField.Value, 'diameter', app.ObjDiaField.Value);
                [app.r, app.RangeField.Value, app.maxPoint, app.FlightTimeField.Value] = ...
                    projMotion(app.y0Field.Value, app.VelocityField.Value, app.ThetaField.Value, app.GravityField.Value, ...
                    'dragType', app.dragType, 'mediumProps', app.mediumProps, 'objectProps', app.objectProps);
                app.MaxHeightField.Value = app.maxPoint(2);
                
                hold(app.UIAxes, lower(app.CombinePlotsToggle.Value));
                
                app.rPositive = app.r(app.r(:,2) >= 0, :);
                plot(app.UIAxes, app.rPositive(:,1), app.rPositive(:,2), 'b');
                axis(app.UIAxes, 'equal');
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, [app.rPositive(1,1) app.rPositive(end,1)], [0 0], 'r--');
                plot(app.UIAxes, app.maxPoint(1), app.maxPoint(2), 'g*');
                plot(app.UIAxes, app.RangeField.Value, 0, 'g*');
                
                hold(app.UIAxes, lower(app.CombinePlotsToggle.Value));
                
            end
        end

        % ClearPlotsButton button pushed function
        function ClearPlotsButtonPushed(app)
            cla(app.UIAxes);
            app.RangeField.Value = 0;
            app.MaxHeightField.Value = 0;
            app.FlightTimeField.Value = 0;
        end

        % ImportDataButton button pushed function
        function ImportDataButtonPushed(app)
            [app.dataFileName, app.dataPathName] = uigetfile({'*.csv'});
            if isequal(app.dataFileName, 0);
                return;
            end
            
            fullPath = strcat(app.dataPathName, app.dataFileName);
            
            try
                app.paramData = load(fullPath);
            catch
                errordlg('Error: input file contains invalid data');
                return;
            end
            
            app.resultData = [];
            
            hold(app.UIAxes, lower(app.CombinePlotsToggle.Value));
            
            for i = 1:size(app.paramData, 1)
                
                app.mediumProps = struct('beta', app.paramData(i,7), 'gamma', app.paramData(i,8));
                app.objectProps = struct('mass', app.paramData(i,5), 'diameter', app.paramData(i,6));
                
                [app.r, range, app.maxPoint, ToF] = projMotion(app.paramData(i,1), app.paramData(i,2), app.paramData(i,3), app.paramData(i,4),...
                    'dragType', 'linquad', 'mediumProps', app.mediumProps, 'objectProps', app.objectProps);
                
                app.rPositive = app.r(app.r(:,2) >= 0, :);
                plot(app.UIAxes, app.rPositive(:,1), app.rPositive(:,2), 'b');
                axis(app.UIAxes, 'equal');
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, [app.rPositive(1,1) app.rPositive(end,1)], [0 0], 'r--');
                plot(app.UIAxes, app.maxPoint(1), app.maxPoint(2), 'g*');
                plot(app.UIAxes, range, 0, 'g*');
                
                app.resultData = [app.resultData; range, app.maxPoint(2), ToF];
            end
            
            hold(app.UIAxes, lower(app.CombinePlotsToggle.Value));
            
            csvwrite('results.csv', app.resultData);
            
            uiwait(helpdlg({'Calculation complete. Results output to:'; ''; fullPath}, 'Status'));
        end

        % ImportHelpButton button pushed function
        function ImportHelpButtonPushed(app)
            helpdlg(sprintf('This feature allows the user to import parameter data from a .csv file, with each data set in a row and columns formatted as follows:\n\ny0, v0, theta0, g, mass, dia, beta, gamma\n\nResults will be output to "results.csv" in the same directory, formatted as:\n\nrange, maxHeight, timeOfFlight\n\nNote: Enter 0 for beta or gamma to turn off linear or quadratic air resistance, respectively.'), 'Import Help');
        end

        % VelocityField value changed function
        function VelocityFieldValueChanged(app)
            if app.VelocityField.Value >= 300
                uiwait(helpdlg('Warning: Simulation is inaccurate at velocities approaching or exceeding the speed of sound (343.2 m/s)', 'Warning'));
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Projectile Motion Calculator';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Trajectory');
            xlabel(app.UIAxes, 'x');
            ylabel(app.UIAxes, 'y');
            app.InitCondsPanel = uipanel(app.UIFigure);
            app.InitCondsPanel.Title = 'Initial Conditions';
            app.InitCondsPanel.Units = 'pixels';
            app.LabelNumericEditField = uilabel(app.InitCondsPanel);
            app.LabelNumericEditField.Text = 'Velocity';
            app.VelocityField = uieditfield(app.InitCondsPanel, 'numeric');
            app.VelocityField.LowerLimitInclusive = 'off';
            app.VelocityField.ValueChangedFcn = createCallbackFcn(app, @VelocityFieldValueChanged);
            app.VelocityField.Limits = [0 200];
            app.VelocityField.Value = 1;
            app.LabelNumericEditField2 = uilabel(app.InitCondsPanel);
            app.LabelNumericEditField2.Text = 'Launch Angle';
            app.ThetaField = uieditfield(app.InitCondsPanel, 'numeric');
            app.ThetaField.UpperLimitInclusive = 'off';
            app.ThetaField.Limits = [0 90];
            app.LabelNumericEditField3 = uilabel(app.InitCondsPanel);
            app.LabelNumericEditField3.Text = 'Initial Height';
            app.y0Field = uieditfield(app.InitCondsPanel, 'numeric');
            app.y0Field.Limits = [0 300];
            app.Label4 = uilabel(app.InitCondsPanel);
            app.Label4.Text = 'Gravity Acceleration';
            app.GravityField = uieditfield(app.InitCondsPanel, 'numeric');
            app.GravityField.Limits = [0 Inf];
            app.GravityField.Value = 9.81;
            app.DragPanel = uipanel(app.UIFigure);
            app.DragPanel.Title = 'Air Resistance';
            app.DragPanel.Units = 'pixels';
            app.LabelSwitch = uilabel(app.DragPanel);
            app.LabelSwitch.Text = 'Linear';
            app.LinToggle = uiswitch(app.DragPanel, 'slider');
            app.LinToggle.ValueChangedFcn = createCallbackFcn(app, @LinToggleValueChanged);
            app.LabelSwitch2 = uilabel(app.DragPanel);
            app.LabelSwitch2.Text = 'Quadratic';
            app.QuadToggle = uiswitch(app.DragPanel, 'slider');
            app.QuadToggle.ValueChangedFcn = createCallbackFcn(app, @QuadToggleValueChanged);
            app.ObjPropsPanel = uipanel(app.DragPanel);
            app.ObjPropsPanel.Title = 'Object Properties';
            app.ObjPropsPanel.Units = 'pixels';
            app.LabelNumericEditField4 = uilabel(app.ObjPropsPanel);
            app.LabelNumericEditField4.Text = 'Mass';
            app.ObjMassField = uieditfield(app.ObjPropsPanel, 'numeric');
            app.ObjMassField.LowerLimitInclusive = 'off';
            app.ObjMassField.Limits = [0 Inf];
            app.ObjMassField.Enable = 'off';
            app.ObjMassField.Value = 1;
            app.Label = uilabel(app.ObjPropsPanel);
            app.Label.Text = 'Diameter';
            app.ObjDiaField = uieditfield(app.ObjPropsPanel, 'numeric');
            app.ObjDiaField.LowerLimitInclusive = 'off';
            app.ObjDiaField.Limits = [0 Inf];
            app.ObjDiaField.Enable = 'off';
            app.ObjDiaField.Value = 1;
            app.MedPropsPanel = uipanel(app.DragPanel);
            app.MedPropsPanel.Title = 'Medium Properties';
            app.MedPropsPanel.Units = 'pixels';
            app.Label2 = uilabel(app.MedPropsPanel);
            app.Label2.Text = 'Beta';
            app.BetaField = uieditfield(app.MedPropsPanel, 'numeric');
            app.BetaField.Limits = [0 Inf];
            app.BetaField.Enable = 'off';
            app.BetaField.Value = 0.00016;
            app.Label3 = uilabel(app.MedPropsPanel);
            app.Label3.Text = 'Gamma';
            app.GammaField = uieditfield(app.MedPropsPanel, 'numeric');
            app.GammaField.Limits = [0 Inf];
            app.GammaField.Enable = 'off';
            app.GammaField.Value = 0.25;
            app.MedPropsDefaultButton = uibutton(app.MedPropsPanel, 'push');
            app.MedPropsDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @MedPropsDefaultButtonPushed);
            app.MedPropsDefaultButton.Enable = 'off';
            app.MedPropsDefaultButton.Text = 'Default';
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.ResultsPanel.Units = 'pixels';
            app.LabelNumericEditField5 = uilabel(app.ResultsPanel);
            app.LabelNumericEditField5.Text = 'Range';
            app.RangeField = uieditfield(app.ResultsPanel, 'numeric');
            app.RangeField.Editable = 'off';
            app.LabelNumericEditField6 = uilabel(app.ResultsPanel);
            app.LabelNumericEditField6.Text = 'Maximum Height';
            app.MaxHeightField = uieditfield(app.ResultsPanel, 'numeric');
            app.MaxHeightField.Editable = 'off';
            app.LabelNumericEditField7 = uilabel(app.ResultsPanel);
            app.LabelNumericEditField7.Text = 'Time of Flight';
            app.FlightTimeField = uieditfield(app.ResultsPanel, 'numeric');
            app.FlightTimeField.Editable = 'off';
            app.ImportDataButton = uibutton(app.UIFigure, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed);
            app.ImportDataButton.Text = 'Import Data from File';
            app.ImportHelpButton = uibutton(app.UIFigure, 'push');
            app.ImportHelpButton.ButtonPushedFcn = createCallbackFcn(app, @ImportHelpButtonPushed);
            app.ImportHelpButton.Text = '?';
            app.ActionPanel = uipanel(app.UIFigure);
            app.ActionPanel.Units = 'pixels';
            app.CalcButton = uibutton(app.ActionPanel, 'push');
            app.CalcButton.ButtonPushedFcn = createCallbackFcn(app, @CalcButtonPushed);
            app.CalcButton.Text = 'Calculate';
            app.ResetParamsButton = uibutton(app.ActionPanel, 'push');
            app.ResetParamsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetParamsButtonButtonPushed);
            app.ResetParamsButton.Text = 'Reset Params';
            app.ClearPlotsButton = uibutton(app.ActionPanel, 'push');
            app.ClearPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotsButtonPushed);
            app.ClearPlotsButton.Text = 'Clear Plots';
            app.LabelSwitch3 = uilabel(app.ActionPanel);
            app.LabelSwitch3.Text = 'Combine Plots';
            app.CombinePlotsToggle = uiswitch(app.ActionPanel, 'slider');
        end
    end

    methods (Access = public)

        % Construct app
        function app = ProjectileMotionCalc()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end